classdef MBM_toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MBMUIFigure                    matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MapsTab                        matlab.ui.container.Tab
        MaplistButton                  matlab.ui.control.Button
        MaskButton                     matlab.ui.control.Button
        SurfaceButton                  matlab.ui.control.Button
        HemisphereDropDown             matlab.ui.control.DropDown
        HemisphereDropDownLabel        matlab.ui.control.Label
        StatTab                        matlab.ui.container.Tab
        DesignmatrixGButton            matlab.ui.control.Button
        StatisticaltestDropDown        matlab.ui.control.DropDown
        PermutationEditField           matlab.ui.control.NumericEditField
        PthrtailapproxEditField        matlab.ui.control.NumericEditField
        PthresholdEditField            matlab.ui.control.NumericEditField
        FDRCheckBox                    matlab.ui.control.CheckBox
        PthresholdEditFieldLabel       matlab.ui.control.Label
        PthrtailapproxEditFieldLabel   matlab.ui.control.Label
        PermutationEditFieldLabel      matlab.ui.control.Label
        StatisticaltestDropDownLabel   matlab.ui.control.Label
        EigenmodesTab                  matlab.ui.container.Tab
        MassmatrixButton               matlab.ui.control.Button
        EigenmodesButton               matlab.ui.control.Button
        NumberofmodesEditField         matlab.ui.control.NumericEditField
        MostinfluentialmodesEditField  matlab.ui.control.NumericEditField
        MostinfluentialmodesEditFieldLabel  matlab.ui.control.Label
        NumberofmodesEditFieldLabel    matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        func = addpath('func')
        util1 = addpath(fullfile('utils','gifti-matlab'))
        util2 = addpath(fullfile('utils','PALM-master'))
        util3 = addpath(fullfile('utils','fdr_bh'))
        util4 = addpath(fullfile('utils','modes'))
        util5 = addpath('utils')
        mbm % mbm structure

        fig % result figure
        StopRequested = false;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % To be removed after testing
            rng(2); % set the default seed for random generation to ensure the results are consistent with demo_2.m with two_sample t-test.

            % set default values
            app.mbm.plot.hemis = app.HemisphereDropDown.Value;
            app.mbm.stat.test = app.StatisticaltestDropDown.Value;
            app.mbm.stat.nPer = app.PermutationEditField.Value;
            app.mbm.stat.pThr = app.PthrtailapproxEditField.Value;
            app.mbm.stat.thres = app.PthresholdEditField.Value;
            app.mbm.stat.fdr = app.FDRCheckBox.Value;
            app.mbm.eig.nEigenmode = app.NumberofmodesEditField.Value;
            app.mbm.plot.nInfluentialMode = app.MostinfluentialmodesEditField.Value;

            app.mbm.plot.visualize = false; % visualise results in aseparate figure (in mbm_main.m)
            app.mbm.eig.saveResult = false; % do not save results
            app.StopButton.Enable = "off";


        end

        % Button pushed function: MaplistButton
        function MaplistButtonPushed(app, event)
            [file,path] = uigetfile('*'); % trying to fix err on MAC ({'*.txt'; '*.mat'; '*.mgh'});
            app.mbm.maps.anatListFile = fullfile(path,file);
            if strcmp(app.mbm.maps.anatListFile,fullfile(0,0))
                app.MaplistButton.BackgroundColor = [0.96 0.96 0.96];
            else
                app.MaplistButton.BackgroundColor = [0.30,0.75,0.93];
            end
            drawnow;
            figure(app.MBMUIFigure);
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.mbm.maps.maskFile = fullfile(path,file);
            if strcmp(app.mbm.maps.maskFile,fullfile(0,0))
                app.MaskButton.BackgroundColor = [0.96 0.96 0.96];
            else
                app.MaskButton.BackgroundColor = [0.30,0.75,0.93];
            end
            drawnow;
            figure(app.MBMUIFigure);
        end

        % Button pushed function: SurfaceButton
        function SurfaceButtonPushed(app, event)
            [file,path] = uigetfile('*.vtk');
            app.mbm.plot.vtkFile = fullfile(path,file);
            if strcmp(app.mbm.plot.vtkFile,fullfile(0,0))
                app.SurfaceButton.BackgroundColor = [0.96 0.96 0.96];
            else
                app.SurfaceButton.BackgroundColor = [0.30,0.75,0.93];
            end
            drawnow;
            figure(app.MBMUIFigure);
        end

        % Drop down opening function: HemisphereDropDown
        function HemisphereDropDownOpening(app, event)
            app.mbm.plot.hemis = app.HemisphereDropDown.Value;
            drawnow
        end

        % Button pushed function: DesignmatrixGButton
        function DesignmatrixGButtonPushed(app, event)
            [file,path] = uigetfile('*');
            app.mbm.stat.designFile = fullfile(path,file);
            if strcmp(app.mbm.stat.designFile,fullfile(0,0))
                app.DesignmatrixGButton.BackgroundColor = [0.96 0.96 0.96];
            else
                app.DesignmatrixGButton.BackgroundColor = [0.30,0.75,0.93];
            end
            drawnow;
            figure(app.MBMUIFigure);
        end

        % Value changed function: StatisticaltestDropDown
        function StatisticaltestDropDownValueChanged(app, event)
            app.mbm.stat.test = app.StatisticaltestDropDown.Value;
            drawnow
        end

        % Value changed function: PermutationEditField
        function PermutationEditFieldValueChanged(app, event)
            app.mbm.stat.nPer = app.PermutationEditField.Value;
            drawnow
        end

        % Value changed function: PthrtailapproxEditField
        function PthrtailapproxEditFieldValueChanged(app, event)
            app.mbm.stat.pThr = app.PthrtailapproxEditField.Value;
            drawnow
        end

        % Value changed function: PthresholdEditField
        function PthresholdEditFieldValueChanged(app, event)
            app.mbm.stat.thres = app.PthresholdEditField.Value;
            drawnow
        end

        % Value changed function: FDRCheckBox
        function FDRCheckBoxValueChanged(app, event)
            app.mbm.stat.fdr = app.FDRCheckBox.Value;
            drawnow
        end

        % Value changed function: MostinfluentialmodesEditField
        function MostinfluentialmodesEditFieldValueChanged(app, event)
            app.mbm.plot.nInfluentialMode = app.MostinfluentialmodesEditField.Value;
            drawnow
        end

        % Button pushed function: EigenmodesButton
        function EigenmodesButtonPushed(app, event)
            [file,path] = uigetfile('*'); % could have use the exact expected file types but the new MACOS has errors with uigetfile

            app.mbm.eig.eigFile = fullfile(path,file);
            if strcmp(app.mbm.eig.eigFile,fullfile(0,0))
                app.EigenmodesButton.BackgroundColor = [0.96 0.96 0.96];
            else
                app.EigenmodesButton.BackgroundColor = [0.30,0.75,0.93];

                drawnow;
                figure(app.MBMUIFigure);
            end
        end

        % Value changed function: NumberofmodesEditField
        function NumberofmodesEditFieldValueChanged(app, event)
            app.mbm.eig.nEigenmode = app.NumberofmodesEditField.Value;
            drawnow
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % close figure
            close all
            allButton= findobj(app.MBMUIFigure,'Type','uicheckbox',...
                '-or', 'Type','uibutton', '-or', 'Type', 'uidropdown','-or','Type','uinumericeditfield');

            set(allButton,'Enable','off')
            app.StopButton.Enable = "on";

            % clear previous results
            app.mbm.maps.mask = [];
            app.mbm.stat.designMatrix = [];
            app.mbm.stat.statMap = [];
            app.mbm.eig.eig =[];
            app.mbm.stat.pMap = [];
            app.mbm.stat.revMap = [];
            app.mbm.stat.thresMap = [];
            app.mbm.eig.beta = [];
            app.mbm.eig.pBeta = [];
            app.mbm.eig.revBeta = [];
            app.mbm.eig.significantBeta = [];
            app.mbm.eig.reconMap = [];
            app.mbm.eig.betaOrder = [];
            clear inputMap statNull statMapNull betaSorted

            % progress bar
            % Change button name to "Processing"
            app.RunButton.Text = 'Processing...';
            % Put text on top of icon
            app.RunButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.RunButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.RunButton.Icon = wbar;

            drawnow
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end

            % check input
            errReturn = mbm_check_input_app(app.mbm); % use different function for the app because the error outputs need to be difference, i.e., 'error' is not good for the app so we use 'msgbox' and break
            if errReturn == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                drawnow
                return
            end
            % update progress bar
            currentProg = min(round((size(app.RunButton.Icon,2)-2)*(1/10)),...
                size(app.RunButton.Icon,2)-2);
            RGB = app.RunButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.RunButton.Icon = RGB;
            drawnow


            %% initialisation. Should have put the follows in a function but then stop button could not interact
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end
            % read inputs from paths
            appUse = true; % indicate of app usage
            [inputMap, app.mbm, errReturn] = mbm_read_inputs(app.mbm, appUse);
            if errReturn == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                drawnow
                return
            end
            errReturn = mbm_check_read_inputs_app(app.mbm, inputMap);
            if errReturn == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                drawnow
                return
            end
            % remove the unused vertices, e.g., the medial wall
            inputMap = inputMap(:, app.mbm.maps.mask == 1);
            app.mbm.eig.eig = app.mbm.eig.eig(app.mbm.maps.mask == 1, 1:app.mbm.eig.nEigenmode);
            app.mbm.eig.mass = app.mbm.eig.mass(app.mbm.maps.mask == 1, app.mbm.maps.mask == 1);

            % update progress bar
            currentProg = min(round((size(app.RunButton.Icon,2)-2)*(1/10)),...
                size(app.RunButton.Icon,2)-2);
            RGB = app.RunButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.RunButton.Icon = RGB;
            drawnow


            %% SBM
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end
            % calculate statistical map
            app.mbm.stat.statMap = mbm_stat_map(inputMap, app.mbm.stat);
            drawnow
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end


            % permutation tests on the statitical map. Can't use
            % mbm_perm_test_map.m because we can't interupt when running
            % that function (can't send stop button to).
            [nSub, nVertice] = size(inputMap);  % number of subjects and number of vertices

            statMapNull = zeros(app.mbm.stat.nPer, size(inputMap,2)); % preallocation space
            for iPer = 1:app.mbm.stat.nPer

                if strcmp(app.mbm.stat.test, 'one sample')

                    % null input maps
                    inputMapNull = inputMap.* sign(rand(nSub,1) - 0.5);

                    % statistical map of the null inputs
                    statMapNull(iPer,:) = mbm_stat_map(inputMapNull, app.mbm.stat);

                else

                    %suffling the labels of the groups
                    iNull = randperm(nSub);

                    statNull = app.mbm.stat;
                    statNull.designMatrix = app.mbm.stat.designMatrix(iNull,:);

                    % statistical map of the null inputs
                    statMapNull(iPer,:) = mbm_stat_map(inputMap, statNull);

                end


                % update progress bar if using app
                currentProg = min(round((size(app.RunButton.Icon,2)-2)*...
                    (1/10+3/10*iPer/app.mbm.stat.nPer)),...% as the mbm_main does not have a single loop, we roughly devided the progress by 10, 1/10 for input checking, 3/10 is for this loop
                    size(app.RunButton.Icon,2)-2);
                RGB = app.RunButton.Icon;
                RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                app.RunButton.Icon = RGB;
                drawnow
                if app.StopRequested == true
                    app.RunButton.Text = 'Run';
                    app.RunButton.Icon = '';
                    app.StopRequested = false;
                    set(allButton,'Enable','on')
                    app.StopButton.Enable = "off";
                    return
                end
            end


            % calculate p-value of the t-map and obtain the thresholded map
            for iVertice = 1:nVertice

                [app.mbm.stat.pMap(iVertice), app.mbm.stat.revMap(iVertice)] = mbm_estimate_p_val_tail(statMapNull(:,iVertice),...
                    app.mbm.stat.statMap(iVertice), app.mbm.stat.pThr); % app.mbm.stat.revMap with value "false" or "true" indicates the observed value is on the right or left tail of the null distribution.

                % update progress bar if using app
                currentProg = min(round((size(app.RunButton.Icon,2)-2)*(1/10+3/10+2/10*iVertice/nVertice)),...
                    size(app.RunButton.Icon,2)-2);
                RGB = app.RunButton.Icon;
                RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                app.RunButton.Icon = RGB;
                drawnow
                if app.StopRequested == true
                    app.RunButton.Text = 'Run';
                    app.RunButton.Icon = '';
                    app.StopRequested = false;
                    set(allButton,'Enable','on')
                    app.StopButton.Enable = "off";
                    return
                end
            end


            % correction with fdr if wishing
            if app.mbm.stat.fdr == 1
                [h, crit_p, adj_ci_cvrg, app.mbm.stat.pMap] = fdr_bh(app.mbm.stat.pMap, app.mbm.stat.thres, 'pdep');
            end
            drawnow
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end
drawnow
            % thresholded map
            app.mbm.stat.thresMap = sign(app.mbm.stat.statMap);
            app.mbm.stat.thresMap(app.mbm.stat.pMap > app.mbm.stat.thres) = 0;

            %% MBM
            % normalize the eigenmodes
            [app.mbm.eig.eig, errReturn] = mbm_normalize_eig(app.mbm.eig.eig, app.mbm.eig.nEigenmode, appUse);
            if errReturn == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                drawnow
                return
            end
            drawnow
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end

            % eigenmode decomposision
            app.mbm.eig.beta = calc_eigendecomposition(app.mbm.stat.statMap', app.mbm.eig.eig, 'orthogonal', app.mbm.eig.mass);
            app.mbm.eig.beta = app.mbm.eig.beta';
            drawnow
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end

            % permutation tests on the beta spectrum
            app.mbm = mbm_perm_test_beta(statMapNull, app.mbm);
            drawnow
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end

            % significant betas
            app.mbm.eig.significantBeta = app.mbm.eig.beta;
            app.mbm.eig.significantBeta(app.mbm.eig.pBeta > app.mbm.stat.thres) = 0;
            nSig = sum(app.mbm.eig.significantBeta~=0);
            if nSig < app.mbm.plot.nInfluentialMode
                uialert(app.MBMUIFigure, ['Only ', num2str(nSig), ' significant modes'],'Warning');
                app.mbm.plot.nInfluentialMode = nSig;
            end

            % sort significant beta
            [betaSorted, app.mbm.eig.betaOrder] = sort(abs(app.mbm.eig.significantBeta), 'descend');
            app.mbm.eig.betaOrder(sum(app.mbm.eig.significantBeta~=0)+1:end) = 0;
            % sigificant pattern
            app.mbm.eig.reconMap = app.mbm.eig.significantBeta * app.mbm.eig.eig';
            drawnow
            if app.StopRequested == true
                app.RunButton.Text = 'Run';
                app.RunButton.Icon = '';
                app.StopRequested = false;
                set(allButton,'Enable','on')
                app.StopButton.Enable = "off";
                return
            end


            app.RunButton.Text = 'Run';
            app.RunButton.Icon = '';
            set(allButton,'Enable','on')
            app.StopButton.Enable = "off";

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopRequested = true;
            msgbox('Run interupted');
            allButton= findobj(app.MBMUIFigure,'Type','uicheckbox',...
                '-or', 'Type','uibutton', '-or', 'Type', 'uidropdown','-or','Type','uinumericeditfield');

            set(allButton,'Enable','on')
            app.StopButton.Enable = "off";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            MBM = app.mbm;
            uisave('MBM');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            plotOut = mbm_plot(app.mbm);
            if isa(plotOut,"logical") % err exists
                return

            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if strcmp(fullfile(path,file),fullfile(0,0))
                app.LoadButton.BackgroundColor = [0.96 0.96 0.96];

            else
                matStruct = load(fullfile(path,file));
                if isfield(matStruct,"MBM") == 0
                    msgbox('Expected input not available');
                    
                    
                    % drawnow
                    % figure(app.MBMUIFigure);

                else
                    app.mbm = matStruct.MBM;

                    app.LoadButton.BackgroundColor = [0.30,0.75,0.93];
                    app.MaplistButton.BackgroundColor = [0.30,0.75,0.93];
                    app.MaskButton.BackgroundColor = [0.30,0.75,0.93];
                    app.SurfaceButton.BackgroundColor = [0.30,0.75,0.93];
                    app.DesignmatrixGButton.BackgroundColor = [0.30,0.75,0.93];
                    app.EigenmodesButton.BackgroundColor = [0.30,0.75,0.93];
                    app.MassmatrixButton.BackgroundColor = [0.30,0.75,0.93];


                    % change display
                    app.HemisphereDropDown.Value = app.mbm.plot.hemis;
                    app.StatisticaltestDropDown.Value = app.mbm.stat.test;
                    app.PermutationEditField.Value = app.mbm.stat.nPer;
                    app.PthrtailapproxEditField.Value = app.mbm.stat.pThr;
                    app.PthresholdEditField.Value = app.mbm.stat.thres;
                    app.FDRCheckBox.Value = app.mbm.stat.fdr;
                    app.NumberofmodesEditField.Value = app.mbm.eig.nEigenmode;
                    app.MostinfluentialmodesEditField.Value = app.mbm.plot.nInfluentialMode;


                    drawnow;
                    figure(app.MBMUIFigure);
                end
            end
        end

        % Value changed function: HemisphereDropDown
        function HemisphereDropDownValueChanged(app, event)
            app.mbm.plot.hemis = app.HemisphereDropDown.Value;
            drawnow
        end

        % Button pushed function: MassmatrixButton
        function MassmatrixButtonPushed(app, event)
            [file,path] = uigetfile('*'); % could have use the exact expected file types but the new MACOS has errors with uigetfile

            app.mbm.eig.massFile = fullfile(path,file);
            if strcmp(app.mbm.eig.massFile,fullfile(0,0))
                app.MassmatrixButton.BackgroundColor = [0.96 0.96 0.96];
            else
                app.MassmatrixButton.BackgroundColor = [0.30,0.75,0.93];

                drawnow;
                figure(app.MBMUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MBMUIFigure = uifigure('Visible', 'off');
            app.MBMUIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.MBMUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotButton = uibutton(app.MBMUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.MBMUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StopButton = uibutton(app.MBMUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.MBMUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TabGroup = uitabgroup(app.MBMUIFigure);
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps     ';
            app.HemisphereDropDownLabel = uilabel(app.MapsTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.MapsTab);
            app.HemisphereDropDown.Items = {'left', 'right'};
            app.HemisphereDropDown.DropDownOpeningFcn = createCallbackFcn(app, @HemisphereDropDownOpening, true);
            app.HemisphereDropDown.ValueChangedFcn = createCallbackFcn(app, @HemisphereDropDownValueChanged, true);
            app.HemisphereDropDown.Value = 'left';
            app.SurfaceButton = uibutton(app.MapsTab, 'push');
            app.SurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceButtonPushed, true);
            app.SurfaceButton.Text = 'Surface';
            app.MaskButton = uibutton(app.MapsTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.MaplistButton = uibutton(app.MapsTab, 'push');
            app.MaplistButton.ButtonPushedFcn = createCallbackFcn(app, @MaplistButtonPushed, true);
            app.MaplistButton.Text = 'Map list';
            app.StatTab = uitab(app.TabGroup);
            app.StatTab.Title = 'Stat';
            app.StatisticaltestDropDownLabel = uilabel(app.StatTab);
            app.StatisticaltestDropDownLabel.Text = 'Statistical test';
            app.PermutationEditFieldLabel = uilabel(app.StatTab);
            app.PermutationEditFieldLabel.Text = 'Permutation';
            app.PthrtailapproxEditFieldLabel = uilabel(app.StatTab);
            app.PthrtailapproxEditFieldLabel.Text = 'Pthr: tail approx';
            app.PthresholdEditFieldLabel = uilabel(app.StatTab);
            app.PthresholdEditFieldLabel.Text = 'P threshold';
            app.FDRCheckBox = uicheckbox(app.StatTab);
            app.FDRCheckBox.ValueChangedFcn = createCallbackFcn(app, @FDRCheckBoxValueChanged, true);
            app.FDRCheckBox.Text = 'FDR';
            app.PthresholdEditField = uieditfield(app.StatTab, 'numeric');
            app.PthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PthresholdEditFieldValueChanged, true);
            app.PthresholdEditField.Value = 0.05;
            app.PthrtailapproxEditField = uieditfield(app.StatTab, 'numeric');
            app.PthrtailapproxEditField.ValueChangedFcn = createCallbackFcn(app, @PthrtailapproxEditFieldValueChanged, true);
            app.PthrtailapproxEditField.Value = 0.1;
            app.PermutationEditField = uieditfield(app.StatTab, 'numeric');
            app.PermutationEditField.ValueChangedFcn = createCallbackFcn(app, @PermutationEditFieldValueChanged, true);
            app.PermutationEditField.Value = 10;
            app.StatisticaltestDropDown = uidropdown(app.StatTab);
            app.StatisticaltestDropDown.Items = {'one sample', 'two sample', 'one way ANOVA', 'ANCOVA_F', 'ANCOVA_Z'};
            app.StatisticaltestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticaltestDropDownValueChanged, true);
            app.StatisticaltestDropDown.Value = 'two sample';
            app.DesignmatrixGButton = uibutton(app.StatTab, 'push');
            app.DesignmatrixGButton.ButtonPushedFcn = createCallbackFcn(app, @DesignmatrixGButtonPushed, true);
            app.DesignmatrixGButton.Text = 'Design matrix G';
            app.EigenmodesTab = uitab(app.TabGroup);
            app.EigenmodesTab.Title = 'Eigenmodes';
            app.NumberofmodesEditFieldLabel = uilabel(app.EigenmodesTab);
            app.NumberofmodesEditFieldLabel.Text = 'Number of modes';
            app.MostinfluentialmodesEditFieldLabel = uilabel(app.EigenmodesTab);
            app.MostinfluentialmodesEditFieldLabel.Text = 'Most influential modes';
            app.MostinfluentialmodesEditField = uieditfield(app.EigenmodesTab, 'numeric');
            app.MostinfluentialmodesEditField.ValueChangedFcn = createCallbackFcn(app, @MostinfluentialmodesEditFieldValueChanged, true);
            app.MostinfluentialmodesEditField.Value = 6;
            app.NumberofmodesEditField = uieditfield(app.EigenmodesTab, 'numeric');
            app.NumberofmodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmodesEditFieldValueChanged, true);
            app.NumberofmodesEditField.Value = 150;
            app.EigenmodesButton = uibutton(app.EigenmodesTab, 'push');
            app.EigenmodesButton.ButtonPushedFcn = createCallbackFcn(app, @EigenmodesButtonPushed, true);
            app.EigenmodesButton.Text = 'Eigenmodes';
            app.MassmatrixButton = uibutton(app.EigenmodesTab, 'push');
            app.MassmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @MassmatrixButtonPushed, true);
            app.MassmatrixButton.Text = 'Mass matrix';
            % Show the figure after all components are created
            app.MBMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MBM_toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MBMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MBMUIFigure)
        end
    end
end