classdef mbm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModebasedmorphometryMBMUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LoadButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MapsTab                        matlab.ui.container.Tab
        HemisphereDropDown             matlab.ui.control.DropDown
        HemisphereDropDownLabel        matlab.ui.control.Label
        SurfaceButton                  matlab.ui.control.Button
        MaskButton                     matlab.ui.control.Button
        MaplistButton                  matlab.ui.control.Button
        StatTab                        matlab.ui.container.Tab
        FDRCheckBox                    matlab.ui.control.CheckBox
        PthresholdEditField            matlab.ui.control.NumericEditField
        PthresholdEditFieldLabel       matlab.ui.control.Label
        PthrtailapproxEditField        matlab.ui.control.NumericEditField
        PthrtailapproxEditFieldLabel   matlab.ui.control.Label
        PermutationEditField           matlab.ui.control.NumericEditField
        PermutationEditFieldLabel      matlab.ui.control.Label
        designmatrixGButton            matlab.ui.control.Button
        StatisticaltestDropDown        matlab.ui.control.DropDown
        StatisticaltestDropDownLabel   matlab.ui.control.Label
        EigenmodesTab                  matlab.ui.container.Tab
        MostinfluentialmodesEditField  matlab.ui.control.NumericEditField
        MostinfluentialmodesEditFieldLabel  matlab.ui.control.Label
        NumberofmodesEditField         matlab.ui.control.NumericEditField
        NumberofmodesEditFieldLabel    matlab.ui.control.Label
        EigenmodesButton               matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        func = addpath('func')
        util1 = addpath('utils/gifti-matlab')
        util2 = addpath('utils/PALM-master')
        util3 = addpath('utils/fdr_bh')
        util4 = addpath('utils')
        mbm % mbm structure
    end

    properties (Access = private)
        y % test
        % For plotting
        light_gray = [0.5 0.5 0.5]; % define color
        light_green = [0.35 0.65 0.35]; % define color
        font_name = 'Arial'; % font for labels
        font_size = 15;
        font_size_axis = 12;


        fig % structure for plotting
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % To be removed after testing
            rng(2); % set the default seed for random generation to ensure the results are consistent with demo_2.m with two_sample t-test.

            axis(app.UIAxes,'off');
            axis(app.UIAxes, 'image');
            axis(app.UIAxes_2,'off');
            axis(app.UIAxes_2, 'image');
            axis(app.UIAxes_3,'off');
            axis(app.UIAxes_3, 'image');
            axis(app.UIAxes_4,'off');
            axis(app.UIAxes_4, 'image');
            % set default values
            app.mbm.plot.visualize = false; % visualise results in aseparate figure (in mbm_main.m)
            app.mbm.stat.test = 'two sample';   % stat test
            app.mbm.stat.nPer = 10; % number of permutation
            app.mbm.stat.pThr = 0.1; % threshold of p-value for tail estimation
            app.mbm.eig.nEigenmode = 150; % number of eigenmodes for analysing
            app.mbm.plot.hemis = 'left'; % left hemisphere
            app.mbm.plot.nInfluentialMode = 6; % number of most influential modes to plot
            app.mbm.stat.fdr = false; % FDR
            app.mbm.stat.thres = 0.05; % p-value to be considered significant
            app.mbm.eig.saveResult = false; % do not save results
            app.mbm.StopRequested = false; % not stop

        end

        % Button pushed function: MaplistButton
        function MaplistButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt'; '*.mat'; '*.mgh'});
            app.mbm.maps.anatListFile = fullfile(path,file);
            app.MaplistButton.BackgroundColor = [0.30,0.75,0.93];
            drawnow;
            figure(app.ModebasedmorphometryMBMUIFigure);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % clear plots
            axesList = findobj(app.ModebasedmorphometryMBMUIFigure, 'Type', 'axes');
            for iAx = 1:length(axesList)
                aa = axesList(iAx);
                cla(aa);
                aa.Title.String = '';
            end

            cbarList = findobj(app.ModebasedmorphometryMBMUIFigure, 'Type', 'ColorBar');
            for iCb = 1:length(cbarList)
                aa = axesList(iCb);
                cla(aa);
            end

            app.mbm.processRunButtonHandle = mbm_setup_progress_bar(app.RunButton);
            pause(0.0001)
            app.mbm.StopRequested = 0;
            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run/Run';
                app.RunButton.Icon = '';
                return
            end

            % check input
            %mbm_check_input(app.mbm,app.ModebasedmorphometryMBMUIFigure); % check inputs

            % update progress bar
            currentProg = min(round((size(app.RunButton.Icon,2)-2)*(1/10)),...
                size(app.RunButton.Icon,2)-2);
            RGB = app.RunButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.RunButton.Icon = RGB;
            pause(0.0001)
            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run';
                app.RunButton.Icon = '';
                return
            end


            %% initialisation. Should have put the follows in a function but then stop button could not interact
            % read inputs from paths
            [inputMap, app.mbm] = mbm_read_inputs(app.mbm, app.ModebasedmorphometryMBMUIFigure);

            % remove the unused vertices, e.g., the medial wall
            inputMap = inputMap(:, app.mbm.maps.mask == 1);
            app.mbm.eig.eig = app.mbm.eig.eig(app.mbm.maps.mask == 1, 1:app.mbm.eig.nEigenmode);

            %% SBM

            % calculate statistical map
            app.mbm.stat.statMap = mbm_stat_map(inputMap, app.mbm.stat);
            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run';
                app.RunButton.Icon = '';
                return
            end

            % permutation tests on the statitical map. Can't use
            % mbm_perm_test_map.m because we can't interupt when running
            % that function (can't send stop button to).
            [nSub, nVertice] = size(inputMap);  % number of subjects and number of vertices

            statMapNull = zeros(app.mbm.stat.nPer, size(inputMap,2)); % preallocation space
            for iPer = 1:app.mbm.stat.nPer

                if strcmp(app.mbm.stat.test, 'one sample')

                    % null input maps
                    inputMapNull = inputMap.* sign(rand(nSub,1) - 0.5);

                    % statistical map of the null inputs
                    statMapNull(iPer,:) = mbm_stat_map(inputMapNull, app.mbm.stat);

                else

                    %suffling the labels of the groups
                    iNull = randperm(nSub);

                    statNull = app.mbm.stat;
                    statNull.designMatrix = app.mbm.stat.designMatrix(iNull,:);

                    % statistical map of the null inputs
                    statMapNull(iPer,:) = mbm_stat_map(inputMap, statNull);

                end


                % update progress bar if using app
                if isfield(app.mbm, 'processRunButtonHandle')==1
                    currentProg = min(round((size(app.mbm.processRunButtonHandle.Icon,2)-2)*...
                        (1/10+3/10*iPer/app.mbm.stat.nPer)),...% as the mbm_main does not have a single loop, we roughly devided the progress by 10, 1/10 for input checking, 3/10 is for this loop
                        size(app.mbm.processRunButtonHandle.Icon,2)-2);
                    RGB = app.mbm.processRunButtonHandle.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                    app.mbm.processRunButtonHandle.Icon = RGB;
                    pause(0.0001)

                    if app.mbm.StopRequested == 1
                        app.RunButton.Text = 'Interupted/Run';
                        app.RunButton.Icon = '';
                        return
                    end
                end
            end

            % calculate p-value of the t-map and obtain the thresholded map
            for iVertice = 1:nVertice

                [app.mbm.stat.pMap(iVertice), app.mbm.stat.revMap(iVertice)] = mbm_estimate_p_val_tail(statMapNull(:,iVertice),...
                    app.mbm.stat.statMap(iVertice), app.mbm.stat.pThr); % app.mbm.stat.revMap with value "false" or "true" indicates the observed value is on the right or left tail of the null distribution.

                % update progress bar if using app
                if isfield(app.mbm, 'processRunButtonHandle')==1
                    currentProg = min(round((size(app.mbm.processRunButtonHandle.Icon,2)-2)*(1/10+3/10+2/10*iVertice/nVertice)),...
                        size(app.mbm.processRunButtonHandle.Icon,2)-2);
                    RGB = app.mbm.processRunButtonHandle.Icon;
                    RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
                    RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
                    RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
                    app.mbm.processRunButtonHandle.Icon = RGB;
                    pause(0.0001)
                    if app.mbm.StopRequested == 1
                        app.RunButton.Text = 'Interupted/Run';
                        app.RunButton.Icon = '';
                        return
                    end
                end
            end

            % correction with fdr if wishing
            if app.mbm.stat.fdr == 1
                [h, crit_p, adj_ci_cvrg, app.mbm.stat.pMap] = fdr_bh(app.mbm.stat.pMap, app.mbm.stat.thres, 'pdep');
            end

            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run';
                app.RunButton.Icon = '';
                return
            end

            % thresholded map
            app.mbm.stat.thresMap = sign(app.mbm.stat.statMap);
            app.mbm.stat.thresMap(app.mbm.stat.pMap > app.mbm.stat.thres) = 0;

            %% MBM
            % normalize the eigenmodes
            app.mbm.eig.eig = mbm_normalize_eig(app.mbm.eig.eig, app.mbm.eig.nEigenmode);
            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run';
                app.RunButton.Icon = '';
                return
            end

            % eigenmode decomposision
            app.mbm.eig.beta = mbm_eigen_decompose(app.mbm.stat.statMap, app.mbm.eig.eig);
            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run';
                app.RunButton.Icon = '';
                return
            end

            % permutation tests on the beta spectrum
            app.mbm = mbm_perm_test_beta(statMapNull, app.mbm);
            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run';
                app.RunButton.Icon = '';
                return
            end

            % significant betas
            app.mbm.eig.significantBeta = app.mbm.eig.beta;
            app.mbm.eig.significantBeta(app.mbm.eig.pBeta > app.mbm.stat.thres) = 0;
            nSig = sum(app.mbm.eig.significantBeta~=0);
            if nSig < app.mbm.plot.nInfluentialMode
                uialert(app.ModebasedmorphometryMBMUIFigure, ['Only ', num2str(nSig), ' significant modes'],'Warning');
                app.mbm.plot.nInfluentialMode = nSig;
            end

            % sort significant beta
            [betaSorted, app.mbm.eig.betaOrder] = sort(abs(app.mbm.eig.significantBeta), 'descend');

            % sigificant pattern
            app.mbm.eig.reconMap = app.mbm.eig.significantBeta * app.mbm.eig.eig';
            if app.mbm.StopRequested == 1
                app.RunButton.Text = 'Interupted/Run';
                app.RunButton.Icon = '';
                return
            end

            app.RunButton.Text = 'Run';
            app.RunButton.Icon = '';

        end

        % Button pushed function: SurfaceButton
        function SurfaceButtonPushed(app, event)
            [file,path] = uigetfile('*.vtk');
            app.mbm.plot.vtkFile = fullfile(path,file);
            app.SurfaceButton.BackgroundColor = [0.30,0.75,0.93];
            drawnow;
            figure(app.ModebasedmorphometryMBMUIFigure);
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.mbm.maps.maskFile = fullfile(path,file);
            app.MaskButton.BackgroundColor = [0.30,0.75,0.93];
            drawnow;
            figure(app.ModebasedmorphometryMBMUIFigure);
        end

        % Value changed function: StatisticaltestDropDown
        function StatisticaltestDropDownValueChanged(app, event)
            app.mbm.stat.test = app.StatisticaltestDropDown.Value;

        end

        % Button pushed function: designmatrixGButton
        function designmatrixGButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.mbm.stat.designFile = fullfile(path,file);
            app.designmatrixGButton.BackgroundColor = [0.30,0.75,0.93];
            drawnow;
            figure(app.ModebasedmorphometryMBMUIFigure);
        end

        % Value changed function: PermutationEditField
        function PermutationEditFieldValueChanged(app, event)
            app.mbm.stat.nPer = app.PermutationEditField.Value;

        end

        % Value changed function: PthrtailapproxEditField
        function PthrtailapproxEditFieldValueChanged(app, event)
            app.mbm.stat.pThr = app.PthrtailapproxEditField.Value;

        end

        % Button pushed function: EigenmodesButton
        function EigenmodesButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            app.mbm.eig.eigFile = fullfile(path,file);
            app.EigenmodesButton.BackgroundColor = [0.30,0.75,0.93];
            drawnow;
            figure(app.ModebasedmorphometryMBMUIFigure);
        end

        % Value changed function: NumberofmodesEditField
        function NumberofmodesEditFieldValueChanged(app, event)
            app.mbm.eig.nEigenmode = app.NumberofmodesEditField.Value;

        end

        % Value changed function: HemisphereDropDown
        function HemisphereDropDownValueChanged(app, event)
            app.mbm.plot.hemis = app.HemisphereDropDown.Value;

        end

        % Value changed function: MostinfluentialmodesEditField
        function MostinfluentialmodesEditFieldValueChanged(app, event)
            app.mbm.plot.nInfluentialMode = app.MostinfluentialmodesEditField.Value;

        end

        % Value changed function: FDRCheckBox
        function FDRCheckBoxValueChanged(app, event)
            app.mbm.stat.fdr = app.FDRCheckBox.Value;

        end

        % Value changed function: PthresholdEditField
        function PthresholdEditFieldValueChanged(app, event)
            app.mbm.stat.thresMap = app.PthresholdEditField.Value;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % clear axes and colorbars
            axesList = findobj(app.ModebasedmorphometryMBMUIFigure, 'Type', 'axes');
            for iAx = 1:length(axesList)
                aa = axesList(iAx);
                cla(aa);
                aa.Title.String = '';
            end

            cbarList = findobj(app.ModebasedmorphometryMBMUIFigure, 'Type', 'ColorBar');
            for iCb = 1:length(cbarList)
                aa = axesList(iCb);
                cla(aa);
            end

            % set display
            app.LeftPanel.Position = [1 1 245 app.ModebasedmorphometryMBMUIFigure.Position(4)];
            app.RightPanel.Position = [app.LeftPanel.Position(3) 1 app.ModebasedmorphometryMBMUIFigure.Position(3)-app.LeftPanel.Position(3) app.RightPanel.Parent.Position(4)];
            

            app.TabGroup.Position = [1 (app.ModebasedmorphometryMBMUIFigure.Position(4)-250) (app.LeftPanel.Position(3)-2) 250];
            app.UIAxes.Units = "normalized";
            app.UIAxes.Position = [0 0.6 0.25 0.35];
           
            app.UIAxes_2.Units = "normalized";
            app.UIAxes_2.Position = [0.25 0.6 0.25 0.35];
            
            app.UIAxes_3.Units = "normalized";
            app.UIAxes_3.Position = [0.5 0.62 0.48 0.38];
            
            app.UIAxes_4.Units = "normalized";
            app.UIAxes_4.Position = [0 0.05 0.25 0.35];
            app.RightPanel.AutoResizeChildren = 1;
           
            % read surface
            [vertices,faces] = read_vtk(app.mbm.plot.vtkFile);
            app.fig.vertices = vertices';
            app.fig.faces = faces';

            % plot statistical map

            % restore removed vertices
            stat_map_nomask = zeros(size(app.mbm.maps.mask))';
            stat_map_nomask(app.mbm.maps.mask==1) = app.mbm.stat.statMap;

            isCbar = true; % display colorbar
            cbar = mbm_plot_map(app.UIAxes,app.fig.vertices,app.fig.faces,stat_map_nomask,app.mbm.plot.hemis, isCbar);
            cbar.Position =[app.UIAxes.Position(1)+app.UIAxes.Position(3) app.UIAxes.Position(2)+app.UIAxes.Position(4)*0.15 app.UIAxes.Position(3)*0.03 app.UIAxes.Position(4)*0.6];
            app.UIAxes.Title.String = 'statistical map';
            app.UIAxes.FontName = app.font_name;
            app.UIAxes.FontSize = app.font_size;

            % plot thresholded tmap
            thres_map_nomask = zeros(size(app.mbm.maps.mask))';
            thres_map_nomask(app.mbm.maps.mask==1) = app.mbm.stat.thresMap;

            isCbar = false; % display colorbar
            mbm_plot_map(app.UIAxes_2,app.fig.vertices,app.fig.faces,thres_map_nomask,app.mbm.plot.hemis, isCbar);
            % a = patch(app.UIAxes_2, 'Vertices', app.fig.vertices, 'Faces', app.fig.faces, 'FaceVertexCData', thres_map_nomask, ...
            %     'EdgeColor', 'none', 'FaceColor', 'interp');
            % 
            % if strcmpi(app.mbm.plot.hemis, 'left')
            %     view(app.UIAxes_2,[-90 0]);
            % elseif strcmpi(app.mbm.plot.hemis, 'right')
            %     view(app.UIAxes_2,[90 0]);
            % end
            % 
            % camlight(app.UIAxes_2,'headlight')
            % material(a,'dull');
            % colormap(app.UIAxes_2,mbm_bluewhitered(app.UIAxes_2));
            % axis(app.UIAxes_2,'off');
            % axis(app.UIAxes_2, 'image');
            % 
            % if isCbar == 1
            %     varargout{1} = colorbar(axisPlot);
            % end

            %%
            app.UIAxes_2.Title.String = 'thresholded map';
            app.UIAxes_2.FontName = app.font_name;
            app.UIAxes_2.FontSize = app.font_size;

            % plot beta spectrum
            ra = app.mbm.eig.nEigenmode/(max(app.mbm.eig.beta)-min(app.mbm.eig.beta));
            app.UIAxes_3.DataAspectRatio = [ra/2 1 1];
            bar(app.UIAxes_3,app.mbm.eig.beta,'FaceColor',app.light_gray,'EdgeColor',app.light_gray);
            hold(app.UIAxes_3,'on');
            bar(app.UIAxes_3,app.mbm.eig.significantBeta,'FaceColor',app.light_green,'EdgeColor',app.light_green);
            hold(app.UIAxes_3, 'off')
            app.UIAxes_3.Title.String = '\beta spectrum';
            app.UIAxes_3.FontName = app.font_name;
            app.UIAxes_3.FontSize = app.font_size;

            xlabel(app.UIAxes_3, 'mode', 'FontName', app.font_name, 'FontSize', app.font_size_axis);
            ylabel(app.UIAxes_3, '\beta', 'FontName', app.font_name, 'FontSize', app.font_size_axis);

            legendSpec = legend(app.UIAxes_3,'non-significant','significant','NumColumns',2)
            legendSpec.Position = [app.UIAxes_3.Position(1)+app.UIAxes_3.Position(3)*0.2 app.UIAxes_3.Position(2)-app.UIAxes_3.Position(4)*0.1 app.UIAxes_3.Position(3)*0.6 app.UIAxes_3.Position(4)*0.1];
            legend(app.UIAxes_3,'boxoff')
            axis(app.UIAxes_3,'on');

            % plot significant patterns
            recon_map_nomask = zeros(size(app.mbm.maps.mask))';
            recon_map_nomask(app.mbm.maps.mask==1) = app.mbm.eig.reconMap;

            isCbar = false; % display colorbar
            mbm_plot_map(app.UIAxes_4,app.fig.vertices,app.fig.faces,recon_map_nomask,app.mbm.plot.hemis, isCbar);
            app.UIAxes_4.Title.String = 'significant patterns';
            app.UIAxes_4.FontName = app.font_name;
            app.UIAxes_4.FontSize = app.font_size;

            % plot the most influential modes
            % Create MostLabel
            app.Label.Text = 'Most influential modes';
            app.Label.FontName = app.font_name;
            app.Label.FontSize = app.font_size;
            app.Label.Position=[app.RightPanel.Position(1)+app.RightPanel.Position(3)*0.25 app.RightPanel.Position(4)*0.53 app.RightPanel.Position(3)*0.75 30];


            eig_nomask = zeros(size(app.mbm.maps.mask,2),app.mbm.plot.nInfluentialMode);
            eig_nomask(app.mbm.maps.mask==1,:) = app.mbm.eig.eig(:,app.mbm.eig.betaOrder(1:app.mbm.plot.nInfluentialMode)).*sign(app.mbm.eig.beta(app.mbm.eig.betaOrder(1:app.mbm.plot.nInfluentialMode)));

            % define axis para
            % factor_x = 1.1;
            % factor_y = 1.4;
            % init_x = 191;
            % init_y = 6;
            % num_row = 2;    %No of rows
            % num_col = ceil(app.mbm.plot.nInfluentialMode/2);    %No of columns
            % length_x = 522/(factor_x*(num_col-1)+1);
            % length_y = 254/(factor_y*(num_row-1)+1);

            factor_x = 1.1;
            factor_y = 0.9;
            init_x = 0.25;
            init_y = 0;
            num_row = 2;    %No of rows
            num_col = ceil(app.mbm.plot.nInfluentialMode/2);    %No of columns
            length_x = 0.75/(factor_x*(num_col-1)+1);
            length_y = 0.55/(factor_y*(num_row-1)+1);

            for eig_in = 1:app.mbm.plot.nInfluentialMode % influent order of the modes

                i = ceil(eig_in/num_col); % row index
                ii = mod(eig_in+num_col-1,num_col)+1; % column index

                aa = uiaxes(app.RightPanel);
                aa.Units = 'normalized';
                aa.Position = [init_x+factor_x*length_x*(ii-1) init_y+factor_y*length_y*(num_row-i) length_x length_y];

                isCbar = false; % display colorbar
                mbm_plot_map(aa,app.fig.vertices,app.fig.faces,eig_nomask(:,eig_in),app.mbm.plot.hemis, isCbar);

                switch eig_in
                    case 1
                        aa.Title.String = [num2str(eig_in), 'st'];
                    case 2
                        aa.Title.String = [num2str(eig_in), 'nd'];
                    case 3
                        aa.Title.String = [num2str(eig_in), 'rd'];
                    otherwise
                        aa.Title.String = [num2str(eig_in), 'th'];
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.mbm.StopRequested = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            mbm = app.mbm;
            uisave('mbm');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            matStruct = load(fullfile(path,file));
            app.mbm = matStruct.MBM;
            app.LoadButton.BackgroundColor = [0.30,0.75,0.93];
            drawnow;
            figure(app.ModebasedmorphometryMBMUIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ModebasedmorphometryMBMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {244, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModebasedmorphometryMBMUIFigure = uifigure('Visible', 'off');
            app.ModebasedmorphometryMBMUIFigure.Name = 'Mode-based morphometry (MBM)';
            app.ModebasedmorphometryMBMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ModebasedmorphometryMBMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps     ';
            app.MaplistButton = uibutton(app.MapsTab, 'push');
            app.MaplistButton.ButtonPushedFcn = createCallbackFcn(app, @MaplistButtonPushed, true);
            app.MaplistButton.Text = 'Map list';
            app.MaskButton = uibutton(app.MapsTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.SurfaceButton = uibutton(app.MapsTab, 'push');
            app.SurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @SurfaceButtonPushed, true);
            app.SurfaceButton.Text = 'Surface';
            app.HemisphereDropDownLabel = uilabel(app.MapsTab);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.HemisphereDropDown = uidropdown(app.MapsTab);
            app.HemisphereDropDown.Items = {'left', 'right'};
            app.HemisphereDropDown.ValueChangedFcn = createCallbackFcn(app, @HemisphereDropDownValueChanged, true);
            app.HemisphereDropDown.Value = 'left';
            app.StatTab = uitab(app.TabGroup);
            app.StatTab.Title = 'Stat';
            app.StatisticaltestDropDownLabel = uilabel(app.StatTab);
            app.StatisticaltestDropDownLabel.Text = 'Statistical test';
            app.StatisticaltestDropDown = uidropdown(app.StatTab);
            app.StatisticaltestDropDown.Items = {'one sample', 'two sample', 'one way ANOVA', 'ANCOVA'};
            app.StatisticaltestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticaltestDropDownValueChanged, true);
            app.StatisticaltestDropDown.Value = 'two sample';
            app.designmatrixGButton = uibutton(app.StatTab, 'push');
            app.designmatrixGButton.ButtonPushedFcn = createCallbackFcn(app, @designmatrixGButtonPushed, true);
            app.designmatrixGButton.Text = 'design matrix G';
            app.PermutationEditFieldLabel = uilabel(app.StatTab);
            app.PermutationEditFieldLabel.Text = 'Permutation';
            app.PermutationEditField = uieditfield(app.StatTab, 'numeric');
            app.PermutationEditField.ValueChangedFcn = createCallbackFcn(app, @PermutationEditFieldValueChanged, true);
            app.PermutationEditField.Value = 10;
            app.PthrtailapproxEditFieldLabel = uilabel(app.StatTab);
            app.PthrtailapproxEditFieldLabel.Text = 'Pthr: tail approx';
            app.PthrtailapproxEditField = uieditfield(app.StatTab, 'numeric');
            app.PthrtailapproxEditField.ValueChangedFcn = createCallbackFcn(app, @PthrtailapproxEditFieldValueChanged, true);
            app.PthrtailapproxEditField.Value = 0.1;
            app.PthresholdEditFieldLabel = uilabel(app.StatTab);
            app.PthresholdEditFieldLabel.Text = 'P threshold';
            app.PthresholdEditField = uieditfield(app.StatTab, 'numeric');
            app.PthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PthresholdEditFieldValueChanged, true);
            app.PthresholdEditField.Value = 0.05;
            app.FDRCheckBox = uicheckbox(app.StatTab);
            app.FDRCheckBox.ValueChangedFcn = createCallbackFcn(app, @FDRCheckBoxValueChanged, true);
            app.FDRCheckBox.Text = 'FDR';
            app.EigenmodesTab = uitab(app.TabGroup);
            app.EigenmodesTab.Title = 'Eigenmodes';
            app.EigenmodesButton = uibutton(app.EigenmodesTab, 'push');
            app.EigenmodesButton.ButtonPushedFcn = createCallbackFcn(app, @EigenmodesButtonPushed, true);
            app.EigenmodesButton.Text = 'Eigenmodes';
            app.NumberofmodesEditFieldLabel = uilabel(app.EigenmodesTab);
            app.NumberofmodesEditFieldLabel.Text = 'Number of modes';
            app.NumberofmodesEditField = uieditfield(app.EigenmodesTab, 'numeric');
            app.NumberofmodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmodesEditFieldValueChanged, true);
            app.NumberofmodesEditField.Value = 150;
            app.MostinfluentialmodesEditFieldLabel = uilabel(app.EigenmodesTab);
            app.MostinfluentialmodesEditFieldLabel.Text = 'Most influential modes';
            app.MostinfluentialmodesEditField = uieditfield(app.EigenmodesTab, 'numeric');
            app.MostinfluentialmodesEditField.ValueChangedFcn = createCallbackFcn(app, @MostinfluentialmodesEditFieldValueChanged, true);
            app.MostinfluentialmodesEditField.Value = 6;
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.ModebasedmorphometryMBMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mbm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModebasedmorphometryMBMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModebasedmorphometryMBMUIFigure)
        end
    end
end